classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        frwagenUIFigure             matlab.ui.Figure
        LimpiarButton               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        GuardarButton               matlab.ui.control.Button
        EncontrardiferenciasButton  matlab.ui.control.Button
        SeleccionararchivoButton    matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tabs 
        TabGroup
        nTabs = 1
        UITable
        monitor
        tabla_proveedor
        tabla_tienda
        tabla_resultado
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.monitor = get(0,'ScreenSize');
            interfaz = app.frwagenUIFigure.Position;
            x = app.monitor(3)/2-interfaz(3)/2;
            y = app.monitor(4)/2-interfaz(4)/2;
            app.frwagenUIFigure.Position = [x y interfaz(3) interfaz(4)];
            app.frwagenUIFigure.WindowState = 'maximized';
            app.Image.Position = [(app.monitor(3)-340),790,317,47];
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)

            try
                [file,path] = uigetfile({'*.csv';'*.txt';'*.xlsx'},'File Selector');
                route = fullfile(path,file);
                tabla = readtable(route,VariableNamingRule="preserve");
                if app.nTabs == 1
                    app.tabla_proveedor = tabla;
                elseif app.nTabs == 2
                    app.tabla_tienda = tabla;
                end
                app.tabs = cell(1,app.nTabs);
                if app.nTabs == 1
                    app.TabGroup = uitabgroup(app.frwagenUIFigure);
                    app.tabs{app.nTabs} = uitab(app.TabGroup,'Title',file); %create the main tab
                    app.UITable = uitable(app.tabs{app.nTabs});
                    app.UITable.Data = tabla;
                    app.UITable.ColumnName = tabla.Properties.VariableNames;
                    app.TabGroup.Position = [38, 31, (app.monitor(3)-90), (app.monitor(4)-180)];
                    app.UITable.Position = [41, 34, (app.monitor(3)-170), (app.monitor(4)-260)];
                else
                    app.tabs{app.nTabs} = uitab(app.TabGroup,'Title',file );
                    app.UITable = uitable(app.tabs{app.nTabs});
                    app.UITable.Data = tabla;
                    app.UITable.ColumnName = tabla.Properties.VariableNames;
                    app.UITable.Position = [41, 34, (app.monitor(3)-170), (app.monitor(4)-260)];
                end
                app.nTabs = app.nTabs+1;
            catch ME
                errordlg(ME.message,"Error")
            end
        end

        % Button pushed function: EncontrardiferenciasButton
        function EncontrardiferenciasButtonPushed(app, event)
            try
                % ordenar conforme al sku
                app.tabla_proveedor = sortrows(app.tabla_proveedor,"Código");
                app.tabla_tienda = sortrows(app.tabla_tienda,"SKU");
                % cambiar el nombre de codigo por SKU en la tabla del proveedor
                app.tabla_proveedor = renamevars(app.tabla_proveedor,"Código","SKU");
                % eliminar los elementos que no tengan SKU
                app.tabla_proveedor = rmmissing(app.tabla_proveedor,'DataVariables','SKU');
                app.tabla_tienda = rmmissing(app.tabla_tienda,'DataVariables','SKU');
                % crear una tabla que una los elementos que coincidan en SKU
                tabla_unida = innerjoin(app.tabla_proveedor,app.tabla_tienda);
                % codigo para evaluar las condiciones
                for i = 1:height(tabla_unida)
                    A = tabla_unida{i,4};
                    B = tabla_unida{i,5};
                    C = tabla_unida{i,'Precio normal'};
                    D = tabla_unida{i,"Precio rebajado"};
                    if isnan(C)
                        tabla_unida(i,"Precio rebajado") = {B};
                    elseif D<B || B<D
                        tabla_unida(i,"Precio rebajado") = {B};
                    elseif A>C
                        tabla_unida(i,"Precio normal") = {A};
                    end
                end
                tabla_unida = removevars(tabla_unida,{'Cant..','Descripción','Marca','menos -25 %','P. Venta Iva.'});
                tabla_unida = movevars(tabla_unida,"SKU","After","Tipo");
                app.tabla_resultado = tabla_unida;
                app.tabs{app.nTabs} = uitab(app.TabGroup,'Title','tabla_resultado' );
                app.UITable = uitable(app.tabs{app.nTabs});
                app.UITable.Data = app.tabla_resultado;
                app.UITable.ColumnName = app.tabla_resultado.Properties.VariableNames;
                app.UITable.Position = [41, 34, (app.monitor(3)-170), (app.monitor(4)-260)];
                numero = height(app.tabla_resultado);
                mensaje = "Se han actualizado " + num2str(numero) + " articulos ";
                msgbox(mensaje,"replace")
            catch
                errordlg("Poner primero el archivo del proveedor con el formato indicado","Error")
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
           
             try
                destino = uigetdir;
                resultado = [destino '\tabla_resultado.csv'];
                tabla_guardado = app.tabla_resultado;
                tabla_guardado = convertvars(tabla_guardado,@isnumeric,@nanblank);
                writetable(tabla_guardado,resultado,'FileType','text','Encoding','ISO-8859-1')
                msgbox("El archivo se ha guarado con exito")
             catch
                 errordlg("Un error ocurrio al guardar el archivo","Error")
             end
            function output = nanblank(values)
                        mask = isnan(values);
                        if nnz(mask)
                          output = string(values);
                          output(mask) = "";
                          output = char(output);
                        else
                            output = values;
                        end
                        
                    end
        end

        % Close request function: frwagenUIFigure
        function frwagenUIFigureCloseRequest(app, event)
             opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            respuesta = questdlg("¿Seguro que desea limpiar todos los archivos?");
            if strcmp(respuesta,'Yes')
                delete(app.TabGroup)
                app.addprop('TabGroup')
               

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.frwagenUIFigure = uifigure('Visible', 'off');
            app.frwagenUIFigure.Name = 'fürwagen';
            app.frwagenUIFigure.Icon = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos 2000w', 'Logo V.png');
            app.frwagenUIFigure.CloseRequestFcn = createCallbackFcn(app, @frwagenUIFigureCloseRequest, true);
            app.SeleccionararchivoButton = uibutton(app.frwagenUIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.EncontrardiferenciasButton = uibutton(app.frwagenUIFigure, 'push');
            app.EncontrardiferenciasButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrardiferenciasButtonPushed, true);
            app.EncontrardiferenciasButton.Text = 'Encontrar diferencias';
            app.GuardarButton = uibutton(app.frwagenUIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Image = uiimage(app.frwagenUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos svg', 'Logo.svg');
            app.LimpiarButton = uibutton(app.frwagenUIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.ContextMenu = uicontextmenu(app.frwagenUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.frwagenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.frwagenUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.frwagenUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.frwagenUIFigure)
        end
    end
end